classdef GUI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        YawHeadingdegreeGauge          matlab.ui.control.SemicircularGauge
        YawHeadingdegreeGaugeLabel     matlab.ui.control.Label
        PitchdegreeGauge               matlab.ui.control.SemicircularGauge
        PitchdegreeGaugeLabel          matlab.ui.control.Label
        RolldegreeGauge                matlab.ui.control.SemicircularGauge
        RolldegreeLabel                matlab.ui.control.Label
        VehicleAccelerationms2EditField  matlab.ui.control.NumericEditField
        VehicleAccelerationms2Label    matlab.ui.control.Label
        ExitProgramButton              matlab.ui.control.Button
        WheelSteeringAngledegreeGauge  matlab.ui.control.SemicircularGauge
        WheelSteeringAngledegreeGaugeLabel  matlab.ui.control.Label
        StopDataStreamingButton        matlab.ui.control.Button
        VehicleSpeedmphEditField       matlab.ui.control.NumericEditField
        VehicleSpeedMPHLabel           matlab.ui.control.Label
        StartDataStreamingButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ThrottleGauge                  matlab.ui.control.LinearGauge
        ThrottleGaugeLabel             matlab.ui.control.Label
        RFWheelRPMGauge                matlab.ui.control.Gauge
        RFWheelRPMLabel                matlab.ui.control.Label
        LFWheelRPMGauge                matlab.ui.control.Gauge
        LFWheelRPMLabel                matlab.ui.control.Label
        LRWheelRPMGauge                matlab.ui.control.Gauge
        LRWheelRPMGaugeLabel           matlab.ui.control.Label
        RRWheelRPMGauge                matlab.ui.control.Gauge
        RRWheelRPMGaugeLabel           matlab.ui.control.Label
        GraphsTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        WheelSpeedTab                  matlab.ui.container.Tab
        WheelSpeedGraph                matlab.ui.control.UIAxes
        VehicleSpeedTab                matlab.ui.container.Tab
        CellVoltageTab                 matlab.ui.container.Tab
        SetupTab                       matlab.ui.container.Tab
        GPSFixLamp                     matlab.ui.control.Lamp
        GPSFixLampLabel                matlab.ui.control.Label
        SSHCarNameEditField            matlab.ui.control.NumericEditField
        SSHCarNameEditFieldLabel       matlab.ui.control.Label
        SSHPortNumberEditField         matlab.ui.control.NumericEditField
        SSHPortNumberEditFieldLabel    matlab.ui.control.Label
        SSHPasswordEditField           matlab.ui.control.EditField
        SSHPasswordEditFieldLabel      matlab.ui.control.Label
        SSHAddressEditField            matlab.ui.control.EditField
        SSHAddressEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopDataStreamingButton
        function StopDataStreamingButtonPushed(app, event)
            global EndDataCollection;
            EndDataCollection = true;
            app.VehicleSpeedmphEditField.Value = 50;
            app.GPSFixLamp.Color = [1 0 0];
        end

        % Button pushed function: StartDataStreamingButton
        function StartDataStreamingButtonPushed(app, event)
             %While app running
             global EndDataCollection;
             %EndDataCollection = false;
             fileName = "TODO.csv";
             recentEOF = 0;
             data = {};    
             disp(EndDataCollection)

             if EndDataCollection == true
                 ThrottlePosition = -27;
                 app.GPSFixLamp.Color = [0 0 1];
                 app.ThrottleGauge.Value = ThrottlePosition;
                 if ThrottlePosition > 0
                    app.ThrottleGauge.ScaleColors = [0 1 0];
                    app.ThrottleGauge.ScaleColorLimits = [0 ThrottlePosition];
                 else
                    app.ThrottleGauge.ScaleColors = [1 0 0];
                    app.ThrottleGauge.ScaleColorLimits = [ThrottlePosition, 0];
                 end
             end
            
             % while EndDataCollection ~= true
             %    % Get data from SSH
             %    fileID = fopen(fileName, 'r');
             %    if fileID == -1
             %        continue
             %    end
             % 
             %    % Initialize variables
             %    fseek(fileID, -1, 'eof');
             %    pos = ftell(fileID);
             %    currentEOF = pos;
             %    line_count = 0;
             % 
             %    while pos > 0 && (line_count < min((currentEOF - recentEOF), 100))
             %        fseek(fileID, pos, 'bof');
             %        if fread(fileID, 1, 'char') == newline
             %            line_count = line_count + 1;
             %        end
             %        pos = pos - 1;
             %    end
             % 
             %    line = fgetl(fileID);
             %    while ischar(line)
             %        data{end + 1} = str2double(line);
             %        line = fgetl(fileID);
             %    end
             %    fclose(fileID);
             %    recentEOF = currentEOF;


                % Variable Assignment
                data = {};
                ThrottlePosition = 37;
                % % SteeringPWM = ;
                % % ThrottlePWM = ;
                % % LFWheelRPM = ;
                % % RFWheelRPM = ;
                % % LRWheelRPM = ;
                % % RRWheelRPM = ;
                % % Cell1Voltage = ;
                % % Cell2Voltage = ;
                % % Cell3Voltage = ;
                % % Cell4Voltage = ;
                % % Cell5Voltage = ;
                % % Cell6Voltage = ;
                % % RollAngle = ;
                % % PitchAngle = ;
                % % YawAngle = ;
                % % GPSFix = ;


                % Math
                % TotalVoltage = sum([Cell1Voltage Cell2Voltage Cell3Voltage Cell4Voltage Cell5Voltage Cell6Voltage]);
                % % SteeringAngle = ;
                % ThrottlePosition = (ThrottlePWM-1500)/500;

                % Plotting


                % 
            
    
        end

        % Button pushed function: ExitProgramButton
        function ExitProgramButtonPushed(app, event)
            app.delete()
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'autumn');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.RRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.RRWheelRPMGaugeLabel.Text = 'RR Wheel RPM';
            app.RRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RRWheelRPMGauge.Limits = [0 2600];
            app.LRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.LRWheelRPMGaugeLabel.Text = 'LR Wheel RPM';
            app.LRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LRWheelRPMGauge.Limits = [0 2600];
            app.LFWheelRPMLabel = uilabel(app.HomeTab);
            app.LFWheelRPMLabel.Text = 'LF Wheel RPM';
            app.LFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LFWheelRPMGauge.Limits = [0 2400];
            app.RFWheelRPMLabel = uilabel(app.HomeTab);
            app.RFWheelRPMLabel.Text = 'RF Wheel RPM';
            app.RFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RFWheelRPMGauge.Limits = [0 2400];
            app.ThrottleGaugeLabel = uilabel(app.HomeTab);
            app.ThrottleGaugeLabel.Interpreter = 'latex';
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.HomeTab, 'linear');
            app.ThrottleGauge.Limits = [-100 100];
            app.ThrottleGauge.MajorTicks = [-100 -50 0 50 100];
            app.ThrottleGauge.MajorTickLabels = {'-100%', '-50%', '0%', '50%', '100%'};
            app.UITable = uitable(app.HomeTab);
            app.UITable.ColumnName = {'Cell'; 'Voltage'};
            app.UITable.RowName = {'Cell 1, Cell 2, Cell 3, Cell 4, Cell 5, Cell 6'};
            app.StartDataStreamingButton = uibutton(app.HomeTab, 'push');
            app.StartDataStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataStreamingButtonPushed, true);
            app.StartDataStreamingButton.Text = 'Start Data Streaming';
            app.VehicleSpeedMPHLabel = uilabel(app.HomeTab);
            app.VehicleSpeedMPHLabel.Interpreter = 'latex';
            app.VehicleSpeedMPHLabel.Text = 'Vehicle Speed ($mph$)';
            app.VehicleSpeedmphEditField = uieditfield(app.HomeTab, 'numeric');
            app.VehicleSpeedmphEditField.Editable = 'off';
            app.StopDataStreamingButton = uibutton(app.HomeTab, 'push');
            app.StopDataStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StopDataStreamingButtonPushed, true);
            app.StopDataStreamingButton.Text = 'Stop Data Streaming';
            app.WheelSteeringAngledegreeGaugeLabel = uilabel(app.HomeTab);
            app.WheelSteeringAngledegreeGaugeLabel.Interpreter = 'latex';
            app.WheelSteeringAngledegreeGaugeLabel.Text = 'Wheel Steering Angle (\degree)';
            app.WheelSteeringAngledegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.WheelSteeringAngledegreeGauge.Limits = [-45 45];
            app.ExitProgramButton = uibutton(app.HomeTab, 'push');
            app.ExitProgramButton.ButtonPushedFcn = createCallbackFcn(app, @ExitProgramButtonPushed, true);
            app.ExitProgramButton.Text = 'Exit Program';
            app.VehicleAccelerationms2Label = uilabel(app.HomeTab);
            app.VehicleAccelerationms2Label.Interpreter = 'latex';
            app.VehicleAccelerationms2Label.Text = 'Vehicle Acceleration ($m/s^{2}$)';
            app.VehicleAccelerationms2EditField = uieditfield(app.HomeTab, 'numeric');
            app.VehicleAccelerationms2EditField.Editable = 'off';
            app.RolldegreeLabel = uilabel(app.HomeTab);
            app.RolldegreeLabel.Interpreter = 'latex';
            app.RolldegreeLabel.Text = 'Roll (\degree)';
            app.RolldegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.RolldegreeGauge.Limits = [-45 45];
            app.PitchdegreeGaugeLabel = uilabel(app.HomeTab);
            app.PitchdegreeGaugeLabel.Interpreter = 'latex';
            app.PitchdegreeGaugeLabel.Text = 'Pitch (\degree)';
            app.PitchdegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.PitchdegreeGauge.Limits = [-45 45];
            app.YawHeadingdegreeGaugeLabel = uilabel(app.HomeTab);
            app.YawHeadingdegreeGaugeLabel.Interpreter = 'latex';
            app.YawHeadingdegreeGaugeLabel.Text = 'Yaw/Heading (\degree)';
            app.YawHeadingdegreeGauge = uigauge(app.HomeTab, 'semicircular');
            app.YawHeadingdegreeGauge.Limits = [-180 180];
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.WheelSpeedTab = uitab(app.TabGroup2);
            app.WheelSpeedTab.Title = 'Wheel Speed';
            app.WheelSpeedGraph = uiaxes(app.WheelSpeedTab);
            title(app.WheelSpeedGraph, 'Wheel Speed vs Time')
            xlabel(app.WheelSpeedGraph, 'dt')
            ylabel(app.WheelSpeedGraph, 'RPM')
            zlabel(app.WheelSpeedGraph, 'Z')
            app.VehicleSpeedTab = uitab(app.TabGroup2);
            app.VehicleSpeedTab.Title = 'Vehicle Speed';
            app.CellVoltageTab = uitab(app.TabGroup2);
            app.CellVoltageTab.Title = 'Cell Voltage';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SSHAddressEditFieldLabel = uilabel(app.SetupTab);
            app.SSHAddressEditFieldLabel.Text = 'SSH Address';
            app.SSHAddressEditField = uieditfield(app.SetupTab, 'text');
            app.SSHAddressEditField.Value = 'rcCar';
            app.SSHPasswordEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPasswordEditFieldLabel.Text = 'SSH Password';
            app.SSHPasswordEditField = uieditfield(app.SetupTab, 'text');
            app.SSHPasswordEditField.Value = 'admin';
            app.SSHPortNumberEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPortNumberEditFieldLabel.Text = 'SSH Port Number';
            app.SSHPortNumberEditField = uieditfield(app.SetupTab, 'numeric');
            app.SSHCarNameEditFieldLabel = uilabel(app.SetupTab);
            app.SSHCarNameEditFieldLabel.Text = 'SSH Car Name';
            app.SSHCarNameEditField = uieditfield(app.SetupTab, 'numeric');
            app.GPSFixLampLabel = uilabel(app.SetupTab);
            app.GPSFixLampLabel.Text = 'GPS Fix';
            app.GPSFixLamp = uilamp(app.SetupTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end